classdef DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        TabSignal                matlab.ui.container.Tab
        UIAxesSignal             matlab.ui.control.UIAxes
        PanelAudioFile           matlab.ui.container.Panel
        ButtonOpenFile           matlab.ui.control.Button
        ButtonSoundAudio         matlab.ui.control.Button
        SwitchAudio              matlab.ui.control.Switch
        PanelAnalytics           matlab.ui.container.Panel
        EditFieldSignal          matlab.ui.control.EditField
        EditFieldFreqDiscret     matlab.ui.control.NumericEditField
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        ButtonAnalytics          matlab.ui.control.Button
        EditFieldTime            matlab.ui.control.NumericEditField
        Label_7                  matlab.ui.control.Label
        CheckBoxNoise            matlab.ui.control.CheckBox
        SwitchAnalytics          matlab.ui.control.Switch
        ButtonReset_2            matlab.ui.control.Button
        ButtonHandSettingFilter  matlab.ui.control.Button
        ButtonSelectFilter       matlab.ui.control.Button
        PanelViewSignal          matlab.ui.container.Panel
        DropDownSignalFeature    matlab.ui.control.DropDown
        ButtonSoundAudio_2       matlab.ui.control.Button
        ButtonSaveSignal         matlab.ui.control.Button
        DropDownSignalType       matlab.ui.control.DropDown
        Label_8                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        SliderRatio              matlab.ui.control.Slider
        Label_10                 matlab.ui.control.Label
        TabFilter                matlab.ui.container.Tab
        UIAxesFilter             matlab.ui.control.UIAxes
        PanelFilterSettings      matlab.ui.container.Panel
        Slider_1                 matlab.ui.control.Slider
        Label_1                  matlab.ui.control.Label
        EditField_1              matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        Slider_2                 matlab.ui.control.Slider
        Label_4                  matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        Slider_4                 matlab.ui.control.Slider
        Label_3                  matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        Slider_3                 matlab.ui.control.Slider
        PanelCalcSettings        matlab.ui.container.Panel
        DropDownFilter           matlab.ui.control.DropDown
        CheckBoxRealTimeCalc     matlab.ui.control.CheckBox
        CheckBoxScale            matlab.ui.control.CheckBox
        ButtonCalc               matlab.ui.control.Button
        ButtonReset              matlab.ui.control.Button
    end

    
    properties (Access = public)
        filter_param = struct( ...
            'omega_p', 0.1, ... % Частота среза, рад/с
            'omega_s', 0.5, ... % Частота подавления, рад/с
            'R_p', 1, ...     % Неравномерность в полосе пропускания, дБ
            'R_s', 30 ...     % Уровень подавления в полосе заграждения, дБ
            );
        
        filter_analog = struct( ...
            'b', [], ...      % Коэффициенты знаменателя
            'a', [] ...       % Коэффициенты числителя
            );
        
        filter_discrete = struct( ...
            'b', [], ...      % Коэффициенты знаменателя
            'a', [] ...       % Коэффициенты числителя
            );
        
        signal = struct( ...
            'freq_discret', 0, ... % Частота дескретизации, Гц
            'expansion', 1, ...    % Формат хранения аудио-файла (0 - аналитическое представление)
            'length', 0, ...       % Количество отсчётов сигнала
            'DTFT', [], ...        % Отсчёты дискретно-временного преобразование Фурье
            'value', [] ...        % Отсчёты сигнала
            );
        
        signal_filtered;
        
        signal_compressed;
        
        signal_restore;
        
        alert_title = 'Ой-ой... Что-то пошло не так!';
    end
    
    methods (Access = private)
        
        % Учитывает искажение частоты при билинейном преобразовании
        function [omega_s, omega_p] = FrequencyDistortion(app, varargin)
            if (nargin == 1)
                if (app.signal.freq_discret <= 0)
                    throw(MException("CalcFilterAnalogPower:InvalidFrequense", ...
                        "Учёт искажение частоты при билинейном преобразовании" ...
                        + " возможен только при задании частоты дискретизации сигнала!"));
                end
                omega_s = 2 * app.signal.freq_discret * tan(app.filter_param.omega_s / 2);
                omega_p = 2 * app.signal.freq_discret * tan(app.filter_param.omega_p / 2);
            else
                omega_s = 2 * varargin{1} * tan(app.filter_param.omega_s / 2);
                omega_p = 2 * varargin{1} * tan(app.filter_param.omega_p / 2);
            end
        end
        
        function flag = CheckRange(app, v1, v2, v3, v4)
            if (v1 > v2)
                uiconfirm(app.UIFigure, ...
                    "Частота среза должны быть не больше частоты загрождения!" ...
                    + " Установлено предыдущее значение или значение по умолчанию.", ...
                    app.alert_title, 'Options', {'OK'});
                flag = 0; return;
            elseif (v3 > v4)
                uiconfirm(app.UIFigure, ...
                    "Неравномерность в полосе пропускания не может быть больше" ...
                    + " уровня подавления в полосе заграждения!" ...
                    + " Установлено предыдущее значение или значение по умолчанию.", ...
                    app.alert_title, "Options", {'OK'}, "CancelOption", 1);
                flag = 0; return;
            end
            flag = 1;
        end
        
        function result_signal = SwitchSignal(app)
            switch app.DropDownSignalType.Value
                case '1'
                    result_signal = app.signal;
                case '2'
                    result_signal = app.signal_filtered;
                case '3'
                    result_signal = app.signal_compressed;
                case '4'
                    result_signal = app.signal;
                    % result_signal = app.signal_restore;
                    uialert(app.UIFigure, 'В разработке!',  app.alert_title);
                    return;
                otherwise
                    error("Непредвиденное значение %s в функции DropDownSignalFeatureValueChanged", ...
                        app.DropDownSignalType.Value);
            end
        end
        
        function UpdateFilterParameters(app)
            if ((app.EditField_1.Value < 0) || (app.EditField_2.Value < 0) ...
                    || (app.EditField_3.Value < 0) || (app.EditField_4.Value < 0))
                throw(MException("UpdateFilterParameters:InvalidFilterParameters", ...
                    "Обнаружены нестандартные параметры фильтра!"));
            end
            
            app.filter_param.omega_p = app.EditField_1.Value;
            app.filter_param.omega_s = app.EditField_2.Value;
            app.filter_param.R_p = app.EditField_3.Value;
            app.filter_param.R_s = app.EditField_4.Value;
        end
        
        function UpdateSliderValue(app, Slider, EditField, event)
            if (app.CheckRange(app.EditField_1.Value, app.EditField_2.Value, app.EditField_3.Value, ...
                    app.EditField_4.Value))
                limit = Slider.Limits;
                if ((EditField.Value < limit(1)) || (EditField.Value > limit(2)))
                    message = sprintf("Заданное значение %.3f не может быть отображено с" + ...
                        " помощью ползунка! Отменить изменения? (Если \'Нет\', то ползунок будет" + ...
                        " неактивен, пока значение не вернётся в диапазон [%.3f %.3f])", ...
                        EditField.Value, limit(1), limit(2));
                    select = uiconfirm(app.UIFigure, message, app.alert_title, ...
                        "Options", {'Да', 'Нет'}, "CancelOption", 1);
                    switch select
                        case 'Да'
                            EditField.Value = event.PreviousValue;
                        case 'Нет'
                            Slider.Enable = "Off";
                    end
                else
                    Slider.Value = event.Value;
                    Slider.Enable = "On";
                end
            else
                EditField.Value = event.PreviousValue;
            end
        end
        
        function SwitchPanelSignal(app, flag)
            switch flag
                case "Off"
                    app.SwitchAudio.Value = "Off";
                    app.PanelAudioFile.ForegroundColor = [0.65 0.65 0.65];
                    app.ButtonOpenFile.Enable = "Off";
                    app.ButtonSoundAudio.Enable = "Off";
                    
                    app.SwitchAnalytics.Value = "On";
                    app.PanelAnalytics.ForegroundColor = [0.15 0.15 0.15];
                    app.EditFieldSignal.Enable = "On";
                    app.EditFieldFreqDiscret.Enable = "On";
                    app.EditFieldTime.Enable = "On";
                    app.CheckBoxNoise.Enable = "On";
                    app.ButtonAnalytics.Enable = "On";
                    app.Label_5.Enable = "On";
                    app.Label_6.Enable = "On";
                    app.Label_7.Enable = "On";
                case "On"
                    app.SwitchAudio.Value = "On";
                    app.ButtonOpenFile.Enable = "On";
                    app.PanelAudioFile.ForegroundColor = [0.15 0.15 0.15];
                    
                    app.SwitchAnalytics.Value = "Off";
                    app.PanelAnalytics.ForegroundColor = [0.65 0.65 0.65];
                    app.EditFieldSignal.Enable = "Off";
                    app.EditFieldFreqDiscret.Enable = "Off";
                    app.EditFieldTime.Enable = "Off";
                    app.CheckBoxNoise.Enable = "Off";
                    app.ButtonAnalytics.Enable = "Off";
                    app.Label_5.Enable = "Off";
                    app.Label_6.Enable = "Off";
                    app.Label_7.Enable = "Off";
                otherwise
                    error("Непредвиденное значение параметра flag = %s функции UpdateSwitchValue", flag);
            end
        end
        
        function SwitchPanelViewSignal(app, flag)
            if (flag)
                app.DropDownSignalFeature.Enable = "On";
                app.ButtonSoundAudio_2.Enable = "On";
                app.ButtonSaveSignal.Enable = "On";
                app.DropDownSignalType.Enable = "On";
                app.PanelViewSignal.ForegroundColor = [0.15 0.15 0.15];
                app.Label_8.Enable = "On";
                app.Label_9.Enable = "On";
            else
                
                app.DropDownSignalFeature.Enable = "Off";
                app.ButtonSoundAudio_2.Enable = "Off";
                app.ButtonSaveSignal.Enable = "Off";
                app.DropDownSignalType.Enable = "Off";
                app.PanelViewSignal.ForegroundColor = [0.65 0.65 0.65];
                app.Label_8.Enable = "Off";
                app.Label_9.Enable = "Off";
            end
        end
        
        function ResetTab(app, idTab)
            switch idTab
                case app.TabFilter
                    app.filter_discrete.a = [];
                    app.filter_discrete.b = [];
                    
                    app.EditField_1.Value = 0.2;  app.Slider_1.Value = 0.2;  app.Slider_1.Enable = "On";
                    app.EditField_2.Value = 0.6;  app.Slider_2.Value = 0.6;  app.Slider_2.Enable = "On";
                    app.EditField_3.Value = 3;    app.Slider_3.Value = 3;    app.Slider_3.Enable = "On";
                    app.EditField_4.Value = 50;   app.Slider_4.Value = 50;   app.Slider_4.Enable = "On";
                    app.DropDownFilter.Value = '3';
                    app.CheckBoxScale.Enable = "On";
                    app.CheckBoxScale.Value = 0;
                    app.CheckBoxRealTimeCalc.Value = 0;
                    app.ButtonCalc.Enable = "On";
                    app.UIAxesFilter.cla(); title(app.UIAxesFilter, "");
                    xlabel(app.UIAxesFilter, ""); ylabel(app.UIAxesFilter, "");
                case app.TabSignal
                    app.signal.length = 0;
                    
                    app.UIAxesSignal.cla(); title(app.UIAxesSignal, "");
                    xlabel(app.UIAxesSignal, ""); ylabel(app.UIAxesSignal, "");
                    app.ButtonSelectFilter.Enable = "Off";
                    app.ButtonSoundAudio.Enable = "Off";
                    app.SwitchPanelSignal("On");
                    app.SwitchPanelViewSignal(false);
                    app.DropDownSignalFeature.Value = '1';
                    app.DropDownSignalType.Value = '1';
                    app.SliderRatio.Enable = "Off";
            end
            app.ButtonHandSettingFilter.Enable = "Off";
        end
        
        function SoundAudio(app, audio)
            progress = uiprogressdlg(app.UIFigure, 'Title' , 'Воспроизведение аудио' , ...
                'Message' , 'Подготовка...' , 'Cancelable' , 'on' );
            player = audioplayer(audio.value, audio.freq_discret);
            time_audio = audio.length / audio.freq_discret;
            time_interval = 0.01;
            play(player);
            for step = 0:time_interval:time_audio
                if progress.CancelRequested
                    stop(player);
                    break;
                end
                progress.Value = step / time_audio;
                progress.Message = sprintf("%0.2f с", step);
                pause(time_interval);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResetTab(app.TabFilter);
            app.ResetTab(app.TabSignal);
            app.SwitchPanelSignal("Off");
            
            app.EditFieldSignal.Value = "double( t>2 & t<2.5 ) .* exp(1)";
            app.EditFieldFreqDiscret.Value = 100;
            app.EditFieldTime.Value = 10;
        end

        % Callback function: ButtonCalc, CheckBoxScale, DropDownFilter
        function ButtonPushed(app, event)
            try
                app.UpdateFilterParameters();
%                 [omega_s, omega_p] = app.FrequencyDistortion(0.5);
                [N, w] = buttord(app.filter_param.omega_p, app.filter_param.omega_s, ...
                    app.filter_param.R_p, app.filter_param.R_s);
                [app.filter_discrete.b, app.filter_discrete.a] = butter(N, w);
            catch ME
                uialert(app.UIFigure, ME.message, app.alert_title);
                return;
            end
            
            switch app.DropDownFilter.Value
                case {'1', '2', '3', '4'}
                    app.CheckBoxScale.Enable = "On";
                case {'5', '6'}
                    app.CheckBoxScale.Enable = "Off";
                    app.CheckBoxScale.Value = 0;
                otherwise
                    error("Непредвиденное значение app.DropDownFilter.Value %s в функции ButtonPushed", ...
                        app.DropDownFilter.Value);
            end
            app.DropDownFilterValueChanged(event);
            
            if (app.signal.length > 0)
                app.ButtonHandSettingFilter.Enable = "On";
            end
        end

        % Button pushed function: ButtonReset
        function ButtonResetPushed(app, event)
            app.ResetTab(app.TabFilter);
        end

        % Button pushed function: ButtonReset_2
        function ButtonReset_2Pushed(app, event)
            app.ResetTab(app.TabSignal);
        end

        % Callback function
        function DropDownFilterValueChanged(app, event)
            switch app.DropDownFilter.Value
                case {'1', '2'}
                    uialert(app.UIFigure, 'В разработке!',  app.alert_title);
                    return;
                    %[freq, omega] = freqs(app.filter_analog.b, app.filter_analog.a);
                case {'3', '4'}
                    [freq, omega] = freqz(app.filter_discrete.b, app.filter_discrete.a);
                    omega = omega / pi;
            end
            title(app.UIAxesFilter, app.DropDownFilter.Items(str2double(app.DropDownFilter.Value)));
            xlabel(app.UIAxesFilter, '\omega, рад/с');
            app.UIAxesFilter.XScale = 'linear';
            switch app.DropDownFilter.Value
                case {'1', '3'}
                    if (app.CheckBoxScale.Value == 1)
                        semilogx(app.UIAxesFilter, omega, mag2db(abs(freq)));
                        ylabel(app.UIAxesFilter, 'A(\omega), Дб');
                    else
                        plot(app.UIAxesFilter, omega, abs(freq));
                        ylabel(app.UIAxesFilter, 'A(\omega)');
                    end
                case {'2', '4'}
                    if (app.CheckBoxScale.Value == 1)
                        semilogx(app.UIAxesFilter, omega, unwrap(angle(freq)));
                    else
                        plot(app.UIAxesFilter, omega, unwrap(angle(freq)));
                    end
                    ylabel(app.UIAxesFilter, '\phi(\omega), рад');
                case '5'
                    [h_impulse, t_impulse] = impz(app.filter_discrete.b, app.filter_discrete.a);
                    plot(app.UIAxesFilter, t_impulse, h_impulse);
                    
                    xlabel(app.UIAxesFilter, 't, c');
                    ylabel(app.UIAxesFilter, 'h(t)');
                case '6'
                    F_circle = linspace(0, 2 * pi);
                    z_circle = complex(cos(F_circle), sin(F_circle));
                    plot(app.UIAxesFilter, z_circle, ':', 'LineWidth', 3, 'Color', 'Red');
                    app.UIAxesFilter.NextPlot = 'add';
                    
                    [z, p, ~] = tf2zp(app.filter_discrete.b, app.filter_discrete.a);
                    plot(app.UIAxesFilter, p, '*', 'LineWidth', 2, 'MarkerSize', 10, ...
                        'MarkerEdgeColor', 'Blue');
                    plot(app.UIAxesFilter, z, 'o', 'LineWidth', 2, 'MarkerSize', 10, ...
                        'MarkerEdgeColor', 'Green');
                    
                    app.UIAxesFilter.NextPlot = 'replacechildren';
                    xlabel(app.UIAxesFilter, 'real');
                    ylabel(app.UIAxesFilter, 'imag');
                    legend(app.UIAxesFilter, "stability limit", "pole", "zero");
                otherwise
                    error("Непредвиденное значение %s в функции UpdateSwitchValue", ...
                        app.DropDownFilter.Value);
            end
        end

        % Value changed function: CheckBoxRealTimeCalc
        function CheckBoxRealTimeCalcValueChanged(app, event)
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonCalc.Enable = "Off";
            else
                app.ButtonCalc.Enable = "On";
            end
            
            app.ButtonPushed(event);
        end

        % Value changed function: SwitchAudio
        function SwitchAudioValueChanged(app, event)
            if (app.SwitchAudio.Value == "Off")
                app.SwitchPanelSignal("Off");
            else
                app.SwitchPanelSignal("On");
            end
        end

        % Value changed function: SwitchAnalytics
        function SwitchAnalyticsValueChanged(app, event)
            if (app.SwitchAnalytics.Value == "Off")
                app.SwitchPanelSignal("On");
            else
                app.SwitchPanelSignal("Off");
            end
        end

        % Button pushed function: ButtonOpenFile
        function ButtonOpenFilePushed(app, event)
            [file_signal, path, app.signal.expansion] = uigetfile({ '*.wav', 'WAVE';
                '*.ogg', 'OGG'; '*.flac', 'FLAC'; '*.au', 'AU'; '*.aiff;*.aif', 'AIFF'; ...
                '*.aifc', 'AIFC'; }, 'Выбор файла', 'MultiSelect', 'off');
            if isequal(file_signal, 0)
                app.ButtonSoundAudio.Enable = "Off";
                return;
            end
            
            app.ButtonSoundAudio.Enable = "On";
            [app.signal.value, app.signal.freq_discret] = audioread(fullfile(path, file_signal));
            
            app.signal.length = length(app.signal.value);
            app.DropDownSignalFeatureValueChanged(event);            
            
            app.DropDownSignalFeature.Enable = "On"; % < fix
            app.ButtonSelectFilter.Enable = "On";
            if (~isempty(app.filter_discrete.a) || ~isempty(app.filter_discrete.b))
                app.ButtonHandSettingFilter.Enable = "On";
            end
        end

        % Value changed function: DropDownSignalFeature, 
        % DropDownSignalType
        function DropDownSignalFeatureValueChanged(app, event)
            title(app.UIAxesSignal, ...
                string(app.DropDownSignalFeature.Items(str2double(app.DropDownSignalFeature.Value))) ...
                + '. ' + string(app.DropDownSignalType.Items(str2double(app.DropDownSignalType.Value))) ...
                + ' сигнал');
            xlabel(app.UIAxesFilter, '\omega, рад/с');
            
            temp_signal = app.SwitchSignal();
            
            if (app.DropDownSignalFeature.Value == '1')
                time_line = (0:(temp_signal.length - 1)) ./ temp_signal.freq_discret;
                plot(app.UIAxesSignal, time_line, temp_signal.value);
                ylabel(app.UIAxesFilter, 'A(t)');
                xlabel(app.UIAxesFilter, 't, c');
            else
                temp_signal.DTFT = fft(temp_signal.value);
                if (app.DropDownSignalFeature.Value == '2')
                    two_sided_spectrum = abs(temp_signal.DTFT / temp_signal.length);
                    single_sided_spectrum = two_sided_spectrum(1:(temp_signal.length / 2 + 1));
                    single_sided_spectrum(2:(end - 1)) = 2 * single_sided_spectrum(2:(end - 1));
                    freq_line = temp_signal.freq_discret * (0:(temp_signal.length / 2)) ...
                        / temp_signal.length;
                    plot(app.UIAxesSignal, freq_line, single_sided_spectrum);
%                     plot(app.UIAxesSignal, freq_line, single_sided_spectrum .* length(single_sided_spectrum));
%                     почему умножение на длинну спектра? без умножения правильно, не помню зачем было умножение  
                    ylabel(app.UIAxesFilter, 'A(\omega)');
                elseif (app.DropDownSignalFeature.Value == '3')
                    ylabel(app.UIAxesFilter, '\phi(\omega), рад');
                    
                    uialert(app.UIFigure, 'В разработке!',  app.alert_title);
                    return;
                end
            end
        end

        % Button pushed function: ButtonSoundAudio
        function ButtonSoundAudioPushed(app, event)
            app.SoundAudio(app.signal);
        end

        % Callback function
        function ButtonAnalyticsPushed(app, event)
            app.signal.expansion = 0;
            app.signal.freq_discret = app.EditFieldFreqDiscret.Value;
            t = 0:(1 / app.signal.freq_discret):app.EditFieldTime.Value;
            try
                app.signal.value = eval(app.EditFieldSignal.Value);
            catch ME
                uialert(app.UIFigure, ME.message, app.alert_title);
                return;
            end
            app.signal.length = length(app.signal.value);
            app.DropDownSignalFeatureValueChanged(event);
            
            app.ButtonSelectFilter.Enable = "On";
            if (~isempty(app.filter_discrete.a) || ~isempty(app.filter_discrete.b))
                app.ButtonHandSettingFilter.Enable = "On";
            end
        end

        % Button pushed function: ButtonHandSettingFilter
        function ButtonHandSettingFilterPushed(app, event)
            app.signal_filtered = app.signal;
            
            app.signal_filtered.value = filter(app.filter_discrete.b, app.filter_discrete.a, app.signal.value);
            
            % Compressing
            app.SliderRatioValueChanged(event);
            
            app.SliderRatio.Enable = "On";
            app.SwitchPanelViewSignal(true);
%             app.SliderRatioValueChanged(event);
%             app.DropDownSignalFeatureValueChanged(event);
        end

        % Callback function: ButtonAnalytics, CheckBoxNoise
        function CheckBoxNoiseValueChanged(app, event)
            app.ButtonAnalyticsPushed(event);
            if (app.signal.length == 0)
                return;
            end
            
            if (app.CheckBoxNoise.Value == 1)
                app.signal.value = awgn(app.signal.value, 10);
            end
            app.DropDownSignalFeatureValueChanged(event);
            
            app.DropDownSignalFeature.Enable = "On"; % < fix
        end

        % Button pushed function: ButtonSaveSignal
        function ButtonSaveSignalPushed(app, event)
            if (app.signal.expansion == 0)
                select = uiconfirm(app.UIFigure, ...
                    "Исходный сигнал был задан аналитически!" ...
                    + " Вы уверены, что хотите сохранить его как аудио-файл?", ...
                    app.alert_title, "Options", {'Да', 'Отмена'}, "CancelOption", 2);
                if (select == "Отмена")
                    return;
                end
            end
            
            temp_signal = app.SwitchSignal();
            [file_signal, path] = uiputfile({ '*.wav', 'WAVE';
                '*.ogg', 'OGG'; '*.flac', 'FLAC';}, 'Выбор файла');
            if isequal(file_signal, 0)
                return;
            end
            
            % Нормировка
            temp_signal.value = temp_signal.value / max(abs(temp_signal.value));
            
            try
                audiowrite(fullfile(path, file_signal), temp_signal.value, ...
                    temp_signal.freq_discret, "BitsPerSample", 24);
            catch ME
                uialert(app.UIFigure, ME.message, app.alert_title);
                return;
            end
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            app.UpdateSliderValue(app.Slider_1, app.EditField_1, event)
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.UpdateSliderValue(app.Slider_2, app.EditField_2, event)
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.UpdateSliderValue(app.Slider_3, app.EditField_3, event)
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.UpdateSliderValue(app.Slider_4, app.EditField_4, event)
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: Slider_1
        function Slider_1ValueChanging(app, event)
            if (~app.CheckRange(event.Value, app.EditField_2.Value, 0, 0))
                app.Slider_1.Value = app.EditField_1.Value;
                return;
            else
                app.EditField_1.Value = event.Value;
            end
            
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanging(app, event)
            if (~app.CheckRange(app.EditField_1.Value, app.EditField_2.Value, 0, 0))
                app.Slider_2.Value = app.EditField_2.Value;
                return;
            else
                app.EditField_2.Value = event.Value;
            end
            
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanging(app, event)
            if (~app.CheckRange(0, 0, app.EditField_3.Value, app.EditField_4.Value))
                app.Slider_3.Value = app.EditField_3.Value;
                return;
            else
                app.EditField_3.Value = event.Value;
            end
            
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanging(app, event)
            if (~app.CheckRange(0, 0, app.EditField_3.Value, app.EditField_4.Value))
                app.Slider_4.Value = app.EditField_4.Value;
                return;
            else
                app.EditField_4.Value = event.Value;
            end
            
            if (app.CheckBoxRealTimeCalc.Value == 1)
                app.ButtonPushed(event);
            end
        end

        % Button pushed function: ButtonSelectFilter
        function ButtonSelectFilterPushed(app, event)
            uialert(app.UIFigure, 'В разработке!',  app.alert_title);
            return;
        end

        % Button pushed function: ButtonSoundAudio_2
        function ButtonSoundAudio_2Pushed(app, event)
            try
                temp_signal = app.SwitchSignal();
                app.SoundAudio(temp_signal);
            catch ME
                uialert(app.UIFigure, ME.message, app.alert_title);
                return;
            end
        end

        % Value changed function: SliderRatio
        function SliderRatioValueChanged(app, event)
            app.SliderRatio.Value = round(app.SliderRatio.Value / 5) * 5;
            
            n = round(100 / app.SliderRatio.Value);
            if (n <= 1)
                uialert(app.UIFigure, "Степень сжатия, равная " + num2str(app.SliderRatio.Value) ...
                    +  "%, слишком большая для заданного сигнала! Попробуйте уменьшить данное значение.",  app.alert_title);
                return;
            end
            
            app.signal_compressed = app.signal_filtered;
            
            app.signal_compressed.length = ceil(app.signal_filtered.length * (1 - 1/n));
            app.signal_compressed.value = zeros(1, app.signal_compressed.length, "double");
            
            offset = 0;
            for i = 1:1:app.signal_filtered.length
                if (rem(i, n) ~= 0)
                    app.signal_compressed.value(i - offset) = app.signal_filtered.value(i);
                else
                    offset = offset + 1;
                end
            end
            
            app.signal_compressed.freq_discret = round(app.signal_filtered.freq_discret * (1 - 1/n));            
            app.DropDownSignalFeatureValueChanged(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Курсовая работа';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabSignal = uitab(app.TabGroup);
            app.TabSignal.Title = 'Сигнал';
            app.UIAxesSignal = uiaxes(app.TabSignal);
            title(app.UIAxesSignal, '')
            xlabel(app.UIAxesSignal, '')
            ylabel(app.UIAxesSignal, '')
            app.PanelAudioFile = uipanel(app.TabSignal);
            app.PanelAudioFile.Title = 'Сигнал из файла';
            app.ButtonOpenFile = uibutton(app.PanelAudioFile, 'push');
            app.ButtonOpenFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenFilePushed, true);
            app.ButtonOpenFile.Text = 'Выбрать файл';
            app.ButtonSoundAudio = uibutton(app.PanelAudioFile, 'push');
            app.ButtonSoundAudio.ButtonPushedFcn = createCallbackFcn(app, @ButtonSoundAudioPushed, true);
            app.ButtonSoundAudio.Enable = 'off';
            app.ButtonSoundAudio.Text = 'Прослушать';
            app.SwitchAudio = uiswitch(app.TabSignal, 'slider');
            app.SwitchAudio.ValueChangedFcn = createCallbackFcn(app, @SwitchAudioValueChanged, true);
            app.SwitchAudio.Value = 'On';
            app.PanelAnalytics = uipanel(app.TabSignal);
            app.PanelAnalytics.Title = 'Аналитическое представление сигнала';
            app.EditFieldSignal = uieditfield(app.PanelAnalytics, 'text');
            app.EditFieldFreqDiscret = uieditfield(app.PanelAnalytics, 'numeric');
            app.EditFieldFreqDiscret.Limits = [0 Inf];
            app.EditFieldFreqDiscret.ValueDisplayFormat = '%11.5g';
            app.Label_5 = uilabel(app.PanelAnalytics);
            app.Label_5.Text = 'Функция y(t)';
            app.Label_6 = uilabel(app.PanelAnalytics);
            app.Label_6.Text = 'Частота дескретизации, Гц';
            app.ButtonAnalytics = uibutton(app.PanelAnalytics, 'push');
            app.ButtonAnalytics.ButtonPushedFcn = createCallbackFcn(app, @CheckBoxNoiseValueChanged, true);
            app.ButtonAnalytics.Text = 'Подтвердить';
            app.EditFieldTime = uieditfield(app.PanelAnalytics, 'numeric');
            app.EditFieldTime.Limits = [0 Inf];
            app.Label_7 = uilabel(app.PanelAnalytics);
            app.Label_7.Text = 'Время действия сигнала (начало - 0), с';
            app.CheckBoxNoise = uicheckbox(app.PanelAnalytics);
            app.CheckBoxNoise.ValueChangedFcn = createCallbackFcn(app, @CheckBoxNoiseValueChanged, true);
            app.CheckBoxNoise.Text = 'Аддитивный шум';
            app.SwitchAnalytics = uiswitch(app.TabSignal, 'slider');
            app.SwitchAnalytics.ValueChangedFcn = createCallbackFcn(app, @SwitchAnalyticsValueChanged, true);
            app.ButtonReset_2 = uibutton(app.TabSignal, 'push');
            app.ButtonReset_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonReset_2Pushed, true);
            app.ButtonReset_2.Text = 'Сбросить';
            app.ButtonHandSettingFilter = uibutton(app.TabSignal, 'push');
            app.ButtonHandSettingFilter.ButtonPushedFcn = createCallbackFcn(app, @ButtonHandSettingFilterPushed, true);
            app.ButtonHandSettingFilter.Text = 'Применить синтезированный фильтр';
            app.ButtonSelectFilter = uibutton(app.TabSignal, 'push');
            app.ButtonSelectFilter.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectFilterPushed, true);
            app.ButtonSelectFilter.Text = 'Автоподбор фильтра';
            app.PanelViewSignal = uipanel(app.TabSignal);
            app.PanelViewSignal.Title = 'Параметры отображения';
            app.DropDownSignalFeature = uidropdown(app.PanelViewSignal);
            app.DropDownSignalFeature.Items = {'Временное представление', 'АЧХ ', 'ФЧХ '};
            app.DropDownSignalFeature.ItemsData = {'1', '2', '3'};
            app.DropDownSignalFeature.ValueChangedFcn = createCallbackFcn(app, @DropDownSignalFeatureValueChanged, true);
            app.DropDownSignalFeature.Value = '1';
            app.ButtonSoundAudio_2 = uibutton(app.PanelViewSignal, 'push');
            app.ButtonSoundAudio_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSoundAudio_2Pushed, true);
            app.ButtonSoundAudio_2.Enable = 'off';
            app.ButtonSoundAudio_2.Text = 'Прослушать результат';
            app.ButtonSaveSignal = uibutton(app.PanelViewSignal, 'push');
            app.ButtonSaveSignal.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveSignalPushed, true);
            app.ButtonSaveSignal.Enable = 'off';
            app.ButtonSaveSignal.Text = 'Сохранить результат';
            app.DropDownSignalType = uidropdown(app.PanelViewSignal);
            app.DropDownSignalType.Items = {'Исходный ', 'Отфильтрованный ', 'Сжатый ', 'Восстановленный '};
            app.DropDownSignalType.ItemsData = {'1', '2', '3', '4'};
            app.DropDownSignalType.ValueChangedFcn = createCallbackFcn(app, @DropDownSignalFeatureValueChanged, true);
            app.DropDownSignalType.Value = '1';
            app.Label_8 = uilabel(app.PanelViewSignal);
            app.Label_8.Text = 'Сигнал';
            app.Label_9 = uilabel(app.PanelViewSignal);
            app.Label_9.Text = 'Характеристика';
            app.SliderRatio = uislider(app.TabSignal);
            app.SliderRatio.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SliderRatio.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.SliderRatio.ValueChangedFcn = createCallbackFcn(app, @SliderRatioValueChanged, true);
            app.SliderRatio.MinorTicks = [];
            app.SliderRatio.Value = 50;
            app.Label_10 = uilabel(app.TabSignal);
            app.Label_10.Text = {'Степень'; 'сжатия, %'};
            app.TabFilter = uitab(app.TabGroup);
            app.TabFilter.Title = 'Синтез фильтра';
            app.UIAxesFilter = uiaxes(app.TabFilter);
            title(app.UIAxesFilter, '')
            xlabel(app.UIAxesFilter, '')
            ylabel(app.UIAxesFilter, '')
            app.PanelFilterSettings = uipanel(app.TabFilter);
            app.PanelFilterSettings.Title = 'Характеристики дискретного ФНЧ';
            app.Slider_1 = uislider(app.PanelFilterSettings);
            app.Slider_1.Limits = [0 1];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanging, true);
            app.Slider_1.MinorTicks = [];
            app.Label_1 = uilabel(app.PanelFilterSettings);
            app.Label_1.Text = 'Нормированная частота среза';
            app.EditField_1 = uieditfield(app.PanelFilterSettings, 'numeric');
            app.EditField_1.Limits = [0 Inf];
            app.EditField_1.ValueDisplayFormat = '%11.3g';
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.Label_2 = uilabel(app.PanelFilterSettings);
            app.Label_2.Text = 'Нормированная частота загрождения';
            app.EditField_2 = uieditfield(app.PanelFilterSettings, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%11.3g';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Slider_2 = uislider(app.PanelFilterSettings);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.Label_4 = uilabel(app.PanelFilterSettings);
            app.Label_4.Text = 'Уровень подавления в полосе заграждения';
            app.EditField_4 = uieditfield(app.PanelFilterSettings, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%11.3g';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Slider_4 = uislider(app.PanelFilterSettings);
            app.Slider_4.Limits = [0 90];
            app.Slider_4.MajorTicks = [0 18 36 54 72 90];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.MinorTicks = [];
            app.Label_3 = uilabel(app.PanelFilterSettings);
            app.Label_3.Text = 'Неравномерность в полосе пропускания';
            app.EditField_3 = uieditfield(app.PanelFilterSettings, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%11.3g';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Slider_3 = uislider(app.PanelFilterSettings);
            app.Slider_3.Limits = [0 25];
            app.Slider_3.MajorTicks = [0 5 10 15 20 25];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.MinorTicks = [];
            app.PanelCalcSettings = uipanel(app.TabFilter);
            app.PanelCalcSettings.Title = 'Настройки отображения';
            app.DropDownFilter = uidropdown(app.PanelCalcSettings);
            app.DropDownFilter.Items = {'АЧХ аналогового прототипа ФНЧ', 'ФЧХ аналогового прототипа ФНЧ', 'АЧХ цифрового ФНЧ', 'ФЧХ цифрового ФНЧ', 'Импульсная хар-ка цифрового ФНЧ', 'Расположение нулей и полюсов ФНЧ'};
            app.DropDownFilter.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.DropDownFilter.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DropDownFilter.Value = '1';
            app.CheckBoxRealTimeCalc = uicheckbox(app.PanelCalcSettings);
            app.CheckBoxRealTimeCalc.ValueChangedFcn = createCallbackFcn(app, @CheckBoxRealTimeCalcValueChanged, true);
            app.CheckBoxRealTimeCalc.Text = 'Авторасчёт';
            app.CheckBoxScale = uicheckbox(app.PanelCalcSettings);
            app.CheckBoxScale.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CheckBoxScale.Text = {'Логорифмическйи масштаб'; ''};
            app.ButtonCalc = uibutton(app.TabFilter, 'push');
            app.ButtonCalc.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonCalc.Text = 'Рассчитать';
            app.ButtonReset = uibutton(app.TabFilter, 'push');
            app.ButtonReset.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetPushed, true);
            app.ButtonReset.Text = 'Сбросить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end